classdef ListeningTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Signal1Button          matlab.ui.control.Button
        Signal2Button          matlab.ui.control.Button
        Signal3Button          matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        ButtonGroup_Direction  matlab.ui.container.ButtonGroup
        Button_0deg            matlab.ui.control.RadioButton
        Button_5deg            matlab.ui.control.RadioButton
        Button_10deg           matlab.ui.control.RadioButton
        Button_15deg           matlab.ui.control.RadioButton
        Button_20deg           matlab.ui.control.RadioButton
        Button_25deg           matlab.ui.control.RadioButton
        Button_30deg           matlab.ui.control.RadioButton
        Button_35deg           matlab.ui.control.RadioButton
        Button_40deg           matlab.ui.control.RadioButton
        Button_45deg           matlab.ui.control.RadioButton
        Button_50deg           matlab.ui.control.RadioButton
        Image                  matlab.ui.control.Image
        Button_55deg           matlab.ui.control.RadioButton
        Button_60deg           matlab.ui.control.RadioButton
        Button_65deg           matlab.ui.control.RadioButton
        Button_70deg           matlab.ui.control.RadioButton
        Button_75deg           matlab.ui.control.RadioButton
        Button_80deg           matlab.ui.control.RadioButton
        Button_85deg           matlab.ui.control.RadioButton
        Button_90deg           matlab.ui.control.RadioButton
        Button_95deg           matlab.ui.control.RadioButton
        Button_100deg          matlab.ui.control.RadioButton
        Button_105deg          matlab.ui.control.RadioButton
        Button_110deg          matlab.ui.control.RadioButton
        Button_115deg          matlab.ui.control.RadioButton
        Button_120deg          matlab.ui.control.RadioButton
        Button_125deg          matlab.ui.control.RadioButton
        Button_130deg          matlab.ui.control.RadioButton
        Button_135deg          matlab.ui.control.RadioButton
        Button_140deg          matlab.ui.control.RadioButton
        Button_145deg          matlab.ui.control.RadioButton
        Button_150deg          matlab.ui.control.RadioButton
        Button_155deg          matlab.ui.control.RadioButton
        Button_160deg          matlab.ui.control.RadioButton
        Button_165deg          matlab.ui.control.RadioButton
        Button_170deg          matlab.ui.control.RadioButton
        Button_175deg          matlab.ui.control.RadioButton
        Button_180deg          matlab.ui.control.RadioButton
        WheredoyouhearthesignalfromLabel  matlab.ui.control.Label
        PageLabel              matlab.ui.control.Label
        Signal4Button          matlab.ui.control.Button
        Signal5Button          matlab.ui.control.Button
        Signal6Button          matlab.ui.control.Button
        Signal7Button          matlab.ui.control.Button
        Signal8Button          matlab.ui.control.Button
        Signal9Button          matlab.ui.control.Button
    end

    
    properties (Access = public)
        maxMethods = 9;
        maxScenes = 2;
        maxSignals = 5;
        maxRepetitions = 2;
        maxPages = 1;
        maxMethods_red = 6;
        
        TestValues; %this is a cell that holds all the results of the experiment
        
        randSignal; % contains random order of Signals
        randScene; % contains random order of Scene
        randMethod; % contains random order of Method
        
        Scene;
        Signal;
        Method;
        signalPath;
        Repetition = 1;
        
        sceneIter = 1;
        signalIter = 1;
        
        
        %        signalPathSc1 = {'Sound_Sc1_Unprocessed_Target.wav',...
        %             'Sound_Sc1_m1Target.wav',...
        %             'Sound_Sc1_m2Target.wav',...
        %             'Sound_Sc1_m3Target.wav',...
        %             'Sound_Sc1_m4_c1Target.wav',...
        %             'Sound_Sc1_m4_c3Target.wav',...
        %             'Sound_Sc1_m5_ILD0.2Target.wav',...
        %             'Sound_Sc1_m5_ILD0.6Target.wav',...
        %             'Sound_Sc1_m5_ILD1Target.wav';...
        %             'Sound_Sc1_Unprocessed_r1.wav',...
        %             'Sound_Sc1_m1_r1.wav',...
        %             'Sound_Sc1_m2_r1.wav',...
        %             'Sound_Sc1_m3_r1.wav',...
        %             'Sound_Sc1_m4_c1_r1.wav',...
        %             'Sound_Sc1_m4_c3_r1.wav',...
        %             'Sound_Sc1_m5_r1_ILD0.2.wav',...
        %             'Sound_Sc1_m5_r1_ILD0.6.wav',...
        %             'Sound_Sc1_m5_r1_ILD1.wav';...
        %             'Sound_Sc1_Unprocessed_r2.wav',...
        %             'Sound_Sc1_m1_r2.wav',...
        %             'Sound_Sc1_m2_r2.wav',...
        %             'Sound_Sc1_m3_r2.wav',...
        %             'Sound_Sc1_m4_c1_r2.wav',...
        %             'Sound_Sc1_m4_c3_r2.wav',...
        %             'Sound_Sc1_m5_r2_ILD0.2.wav',...
        %             'Sound_Sc1_m5_r2_ILD0.6.wav',...
        %             'Sound_Sc1_m5_r2_ILD1.wav';...
        %             'Sound_Sc1_Unprocessed_r3.wav',...
        %             'Sound_Sc1_m1_r3.wav',...
        %             'Sound_Sc1_m2_r3.wav',...
        %             'Sound_Sc1_m3_r3.wav',...
        %             'Sound_Sc1_m4_c1_r3.wav',...
        %             'Sound_Sc1_m4_c3_r3.wav',...
        %             'Sound_Sc1_m5_r3_ILD0.2.wav',...
        %             'Sound_Sc1_m5_r3_ILD0.6.wav',...
        %             'Sound_Sc1_m5_r3_ILD1.wav'};
        
        signalPathSc2 = {'Sound_Sc2_Unprocessed_Target.wav',...
            'Sound_Sc2_m1Target.wav',...
            'Sound_Sc2_m2Target.wav',...
            'Sound_Sc2_m3Target.wav',...
            'Sound_Sc2_m4_c1Target.wav',...
            'Sound_Sc2_m4_c3Target.wav',...
            'Sound_Sc2_m5_ILD0.2Target.wav',...
            'Sound_Sc2_m5_ILD0.6Target.wav',...
            'Sound_Sc2_m5_ILD1Target.wav';...
            'Sound_Sc2_Unprocessed_r1.wav',...
            'Sound_Sc2_m1_r1.wav',...
            'Sound_Sc2_m2_r1.wav',...
            'Sound_Sc2_m3_r1.wav',...
            'Sound_Sc2_m4_c1_r1.wav',...
            'Sound_Sc2_m4_c3_r1.wav',...
            'Sound_Sc2_m5_r1_ILD0.2.wav',...
            'Sound_Sc2_m5_r1_ILD0.6.wav',...
            'Sound_Sc2_m5_r1_ILD1.wav';...
            'Sound_Sc2_Unprocessed_r2.wav',...
            'Sound_Sc2_m1_r2.wav',...
            'Sound_Sc2_m2_r2.wav',...
            'Sound_Sc2_m3_r2.wav',...
            'Sound_Sc2_m4_c1_r2.wav',...
            'Sound_Sc2_m4_c3_r2.wav',...
            'Sound_Sc2_m5_r2_ILD0.2.wav',...
            'Sound_Sc2_m5_r2_ILD0.6.wav',...
            'Sound_Sc2_m5_r2_ILD1.wav';...
            'Sound_Sc2_Unprocessed_r3.wav',...
            'Sound_Sc2_m1_r3.wav',...
            'Sound_Sc2_m2_r3.wav',...
            'Sound_Sc2_m3_r3.wav',...
            'Sound_Sc2_m4_c1_r3.wav',...
            'Sound_Sc2_m4_c3_r3.wav',...
            'Silence.wav',...
            'Silence.wav',...
            'Silence.wav';...
            'Sound_Sc2_Unprocessed_r4.wav',...
            'Sound_Sc2_m1_r4.wav',...
            'Sound_Sc2_m2_r4.wav',...
            'Sound_Sc2_m5_r4_ILD0.2.wav',...
            'Sound_Sc2_m5_r4_ILD0.6.wav',...
            'Sound_Sc2_m5_r4_ILD1.wav',...
            'Silence.wav',...
            'Silence.wav',...
            'Silence.wav'};
        
        signalPathSc3 ={'Sound_Sc3_Unprocessed_Target.wav',...
            'Sound_Sc3_m1Target.wav',...
            'Sound_Sc3_m2Target.wav',...
            'Sound_Sc3_m3Target.wav',...
            'Sound_Sc3_m4_c1Target.wav',...
            'Sound_Sc3_m4_c3Target.wav',...
            'Sound_Sc3_m5_ILD0.2Target.wav',...
            'Sound_Sc3_m5_ILD0.6Target.wav',...
            'Sound_Sc3_m5_ILD1Target.wav';...
            'Sound_Sc3_Unprocessed_r1.wav',...
            'Sound_Sc3_m1_r1.wav',...
            'Sound_Sc3_m2_r1.wav',...
            'Sound_Sc3_m3_r1.wav',...
            'Sound_Sc3_m4_c1_r1.wav',...
            'Sound_Sc3_m4_c3_r1.wav',...
            'Sound_Sc3_m5_r1_ILD0.2.wav',...
            'Sound_Sc3_m5_r1_ILD0.6.wav',...
            'Sound_Sc3_m5_r1_ILD1.wav';...
            'Sound_Sc3_Unprocessed_r2.wav',...
            'Sound_Sc3_m1_r2.wav',...
            'Sound_Sc3_m2_r2.wav',...
            'Sound_Sc3_m3_r2.wav',...
            'Sound_Sc3_m4_c1_r2.wav',...
            'Sound_Sc3_m4_c3_r2.wav',...
            'Sound_Sc3_m5_r2_ILD0.2.wav',...
            'Sound_Sc3_m5_r2_ILD0.6.wav',...
            'Sound_Sc3_m5_r2_ILD1.wav';...
            'Sound_Sc3_Unprocessed_r3.wav',...
            'Sound_Sc3_m1_r3.wav',...
            'Sound_Sc3_m2_r3.wav',...
            'Sound_Sc3_m3_r3.wav',...
            'Sound_Sc3_m4_c1_r3.wav',...
            'Sound_Sc3_m4_c3_r3.wav',...
            'Silence.wav',...
            'Silence.wav',...
            'Silence.wav';...
            'Sound_Sc3_Unprocessed_r4.wav',...
            'Sound_Sc3_m1_r4.wav',...
            'Sound_Sc3_m2_r4.wav',...
            'Sound_Sc3_m5_r4_ILD0.2.wav',...
            'Sound_Sc3_m5_r4_ILD0.6.wav',...
            'Sound_Sc3_m5_r4_ILD1.wav',...
            'Silence.wav',...
            'Silence.wav',...
            'Silence.wav'};
        PageCount=1;
        Property21 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.TestValues = zeros(app.maxSignals,app.maxMethods,app.maxScenes,app.maxRepetitions); %this is a cell that holds all the values of the experiment
            app.signalPath = cat(3, app.signalPathSc2, app.signalPathSc3);
            
            app.randSignal = randperm(app.maxSignals);
            app.randScene = randperm(app.maxScenes);
            app.Scene = app.randScene(app.sceneIter);
            app.Signal = app.randSignal(app.signalIter);
            if((app.Signal == 5) ||(app.Signal == 4))
                app.randMethod = [randperm(app.maxMethods_red),7,8,9];
                app.Signal7Button.Enable = 'off';
                app.Signal8Button.Enable = 'off';
                app.Signal9Button.Enable = 'off';
            else
                app.randMethod = randperm(app.maxMethods);
                app.Signal7Button.Enable = 'on';
                app.Signal8Button.Enable = 'on';
                app.Signal9Button.Enable = 'on';
            end
            app.maxPages = app.maxScenes*app.maxSignals*app.maxRepetitions;
            
            app.PageLabel.Text = ['Page ' num2str(app.PageCount) '/' num2str(app.maxPages)];
        end

        % Selection changed function: ButtonGroup_Direction
        function ButtonGroup_DirectionSelectionChanged(app, event)
            if app.Button_0deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -90;
            elseif app.Button_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -85;
            elseif app.Button_10deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -80;
            elseif app.Button_15deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -75;
            elseif app.Button_20deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -70;
            elseif app.Button_25deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -65;
            elseif app.Button_30deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -60;
            elseif app.Button_35deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -55;
            elseif app.Button_40deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -50;
            elseif app.Button_45deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -45;
            elseif app.Button_50deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -40;
            elseif app.Button_55deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -35;
            elseif app.Button_60deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -30;
            elseif app.Button_65deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -25;
            elseif app.Button_70deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -20;
            elseif app.Button_75deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -15;
            elseif app.Button_80deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -10;
            elseif app.Button_85deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = -5;
            elseif app.Button_90deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 0;
            elseif app.Button_95deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 5;
            elseif app.Button_100deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 10;
            elseif app.Button_105deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 15;
            elseif app.Button_110deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 20;
            elseif app.Button_115deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 25;
            elseif app.Button_120deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 30;
            elseif app.Button_125deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 35;
            elseif app.Button_130deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 40;
            elseif app.Button_135deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 45;
            elseif app.Button_140deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 50;
            elseif app.Button_145deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 55;
            elseif app.Button_150deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 60;
            elseif app.Button_155deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 65;
            elseif app.Button_160deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 70;
            elseif app.Button_165deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 75;
            elseif app.Button_170deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 80;
            elseif app.Button_175deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 85;          
            elseif app.Button_180deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 90;
            else
                app.TestValues(app.Signal, app.Method, app.Scene, app.Repetition) = 0;                
            end
            drawnow;
        end

        % Button pushed function: Signal1Button
        function Signal1ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(1);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal1Button.BackgroundColor = 'g';
            sound(y,fs);
        end

        % Button pushed function: Signal2Button
        function Signal2ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(2);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal2Button.BackgroundColor = 'g';
            sound(y,fs);
        end

        % Button pushed function: Signal3Button
        function Signal3ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(3);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal3Button.BackgroundColor = 'g';

            sound(y,fs);
        end

        % Button pushed function: Signal4Button
        function Signal4ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(4);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            
            app.Signal4Button.BackgroundColor = 'g';

            sound(y,fs);
        end

        % Button pushed function: Signal5Button
        function Signal5ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(5);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal5Button.BackgroundColor = 'g';

            sound(y,fs);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            clear sound;
            app.Signal1Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal2Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal3Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal4Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal5Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal6Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal7Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal8Button.BackgroundColor = [0.96,0.96,0.96];
            app.Signal9Button.BackgroundColor = [0.96,0.96,0.96];

            app.PageCount = app.PageCount + 1;
            app.PageLabel.Text = ['Page ' num2str(app.PageCount) '/' num2str(app.maxPages)];
            app.signalIter = app.signalIter + 1;
            if (app.signalIter < app.maxSignals + 1)
                app.Button_90deg.Value = true;
                app.Signal = app.randSignal(app.signalIter);
                if((app.Signal == 5) ||(app.Signal == 4))
                    app.randMethod = [randperm(app.maxMethods_red),7,8,9];
                    app.Signal7Button.Enable = 'off';
                    app.Signal8Button.Enable = 'off';
                    app.Signal9Button.Enable = 'off';
                else
                    app.randMethod = randperm(app.maxMethods);
                    app.Signal7Button.Enable = 'on';
                    app.Signal8Button.Enable = 'on';
                    app.Signal9Button.Enable = 'on';
                end
                
                if ((app.sceneIter == app.maxScenes) && (app.signalIter == app.maxSignals) &&(app.Repetition == app.maxRepetitions))
                    app.NextButton.Text = 'Finish';
                end
            else
                app.signalIter = 1;
                app.sceneIter = app.sceneIter + 1;
                if (app.sceneIter < app.maxScenes + 1)
                    app.Scene = app.randScene(app.sceneIter);
                    app.randSignal = randperm(app.maxSignals);
                    app.randMethod = randperm(app.maxMethods);
                    app.Button_90deg.Value = true;
                    app.Signal = app.randSignal(app.signalIter);
                    if((app.Signal == 5) ||(app.Signal == 4))
                        app.randMethod = [randperm(app.maxMethods_red),7,8,9];
                        app.Signal7Button.Enable = 'off';
                        app.Signal8Button.Enable = 'off';
                        app.Signal9Button.Enable = 'off';
                    else
                        app.randMethod = randperm(app.maxMethods);
                        app.Signal7Button.Enable = 'on';
                        app.Signal8Button.Enable = 'on';
                        app.Signal9Button.Enable = 'on';
                    end
                else
                    app.Repetition = app.Repetition + 1;
                    if(app.Repetition < app.maxRepetitions + 1)
                        app.signalIter = 1;
                        app.sceneIter = 1;
                        
                        app.randScene = randperm(app.maxScenes);
                        app.randSignal = randperm(app.maxSignals);
                        app.randMethod = randperm(app.maxMethods);
                        app.Scene = app.randScene(app.sceneIter);
                        app.Signal = app.randSignal(app.signalIter);
                        if((app.Signal == 5) ||(app.Signal == 4))
                            app.randMethod = [randperm(app.maxMethods_red),7,8,9];
                            app.Signal7Button.Enable = 'off';
                            app.Signal8Button.Enable = 'off';
                            app.Signal9Button.Enable = 'off';
                        else
                            app.randMethod = randperm(app.maxMethods);
                            app.Signal7Button.Enable = 'on';
                            app.Signal8Button.Enable = 'on';
                            app.Signal9Button.Enable = 'on';
                        end
                        app.Button_90deg.Value = true;
                    else
                        filename = 'Result.xlsx';
                        writematrix(app.TestValues(:,:,1,1),filename,"Sheet",1,"Range",'C3:K7');
                        writematrix(app.TestValues(:,:,1,2),filename,"Sheet",1,"Range",'C9:K13');
                        writematrix(app.TestValues(:,:,2,1),filename,"Sheet",1,"Range",'C16:K20');
                        writematrix(app.TestValues(:,:,2,2),filename,"Sheet",1,"Range",'C22:K26');
                        
                        delete(app)
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            filename = 'Result.xlsx';
            writematrix(app.TestValues(:,:,1,1),filename,"Sheet",1,"Range",'C3:K7');
            writematrix(app.TestValues(:,:,1,2),filename,"Sheet",1,"Range",'C9:K13');
            writematrix(app.TestValues(:,:,2,1),filename,"Sheet",1,"Range",'C16:K20');
            writematrix(app.TestValues(:,:,2,2),filename,"Sheet",1,"Range",'C22:K26');
            
            delete(app)
        end

        % Button pushed function: Signal6Button
        function Signal6ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(6);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal6Button.BackgroundColor = 'g';
            sound(y,fs);
        end

        % Button pushed function: Signal7Button
        function Signal7ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(7);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal7Button.BackgroundColor = 'g';
            sound(y,fs);
        end

        % Button pushed function: Signal8Button
        function Signal8ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(8);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal8Button.BackgroundColor = 'g';

            sound(y,fs);
        end

        % Button pushed function: Signal9Button
        function Signal9ButtonPushed(app, event)
            clear sound;
            app.Button_90deg.Value = true;
            app.Method = app.randMethod(9);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            app.Signal9Button.BackgroundColor = 'g';

            sound(y,fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Signal1Button = uibutton(app.UIFigure, 'push');
            app.Signal1Button.ButtonPushedFcn = createCallbackFcn(app, @Signal1ButtonPushed, true);
            app.Signal1Button.Text = 'Signal 1';
            app.Signal2Button = uibutton(app.UIFigure, 'push');
            app.Signal2Button.ButtonPushedFcn = createCallbackFcn(app, @Signal2ButtonPushed, true);
            app.Signal2Button.Text = 'Signal 2';
            app.Signal3Button = uibutton(app.UIFigure, 'push');
            app.Signal3Button.ButtonPushedFcn = createCallbackFcn(app, @Signal3ButtonPushed, true);
            app.Signal3Button.Text = 'Signal 3';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >';
            app.ButtonGroup_Direction = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Direction.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_DirectionSelectionChanged, true);
            app.Button_0deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_0deg.Text = '';
            app.Button_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_5deg.Text = '';
            app.Button_10deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_10deg.Text = '';
            app.Button_15deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_15deg.Text = '';
            app.Button_20deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_20deg.Text = '';
            app.Button_25deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_25deg.Text = '';
            app.Button_30deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_30deg.Text = '';
            app.Button_35deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_35deg.Text = '';
            app.Button_40deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_40deg.Text = '';
            app.Button_45deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_45deg.Text = '';
            app.Button_50deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_50deg.Text = '';
            app.Image = uiimage(app.ButtonGroup_Direction);
            app.Image.ImageSource = 'LoctationImage.PNG';
            app.Button_55deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_55deg.Text = '';
            app.Button_60deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_60deg.Text = '';
            app.Button_65deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_65deg.Text = '';
            app.Button_70deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_70deg.Text = '';
            app.Button_75deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_75deg.Text = '';
            app.Button_80deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_80deg.Text = '';
            app.Button_85deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_85deg.Text = '';
            app.Button_90deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_90deg.Text = '';
            app.Button_90deg.Value = true;
            app.Button_95deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_95deg.Text = '';
            app.Button_100deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_100deg.Text = '';
            app.Button_105deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_105deg.Text = '';
            app.Button_110deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_110deg.Text = '';
            app.Button_115deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_115deg.Text = '';
            app.Button_120deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_120deg.Text = '';
            app.Button_125deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_125deg.Text = '';
            app.Button_130deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_130deg.Text = '';
            app.Button_135deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_135deg.Text = '';
            app.Button_140deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_140deg.Text = '';
            app.Button_145deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_145deg.Text = '';
            app.Button_150deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_150deg.Text = '';
            app.Button_155deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_155deg.Text = '';
            app.Button_160deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_160deg.Text = '';
            app.Button_165deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_165deg.Text = '';
            app.Button_170deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_170deg.Text = '';
            app.Button_175deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_175deg.Text = '';
            app.Button_180deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_180deg.Text = '';
            app.WheredoyouhearthesignalfromLabel = uilabel(app.ButtonGroup_Direction);
            app.WheredoyouhearthesignalfromLabel.Text = 'Where do you hear the signal from?';
            app.PageLabel = uilabel(app.UIFigure);
            app.PageLabel.Text = '';
            app.Signal4Button = uibutton(app.UIFigure, 'push');
            app.Signal4Button.ButtonPushedFcn = createCallbackFcn(app, @Signal4ButtonPushed, true);
            app.Signal4Button.Text = 'Signal 4';
            app.Signal5Button = uibutton(app.UIFigure, 'push');
            app.Signal5Button.ButtonPushedFcn = createCallbackFcn(app, @Signal5ButtonPushed, true);
            app.Signal5Button.Text = 'Signal 5';
            app.Signal6Button = uibutton(app.UIFigure, 'push');
            app.Signal6Button.ButtonPushedFcn = createCallbackFcn(app, @Signal6ButtonPushed, true);
            app.Signal6Button.Text = 'Signal 6';
            app.Signal7Button = uibutton(app.UIFigure, 'push');
            app.Signal7Button.ButtonPushedFcn = createCallbackFcn(app, @Signal7ButtonPushed, true);
            app.Signal7Button.Text = 'Signal 7';
            app.Signal8Button = uibutton(app.UIFigure, 'push');
            app.Signal8Button.ButtonPushedFcn = createCallbackFcn(app, @Signal8ButtonPushed, true);
            app.Signal8Button.Text = 'Signal 8';
            app.Signal9Button = uibutton(app.UIFigure, 'push');
            app.Signal9Button.ButtonPushedFcn = createCallbackFcn(app, @Signal9ButtonPushed, true);
            app.Signal9Button.Text = 'Signal 9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ListeningTestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end